classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup_2                      matlab.ui.container.TabGroup
        NghiemTab_2                     matlab.ui.container.Tab
        saiso                           matlab.ui.control.NumericEditField
        SaisEditFieldLabel              matlab.ui.control.Label
        solanlap                        matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel             matlab.ui.control.Label
        NhpvohmhmlpLabel                matlab.ui.control.Label
        nhappt                          matlab.ui.control.EditField
        ketqua                          matlab.ui.control.EditField
        KtquEditFieldLabel              matlab.ui.control.Label
        khoang                          matlab.ui.control.EditField
        Khongtnhchc1nghimnLabel         matlab.ui.control.Label
        LuKhongtnhphiccphpabLabel       matlab.ui.control.Label
        TnhvvButton                     matlab.ui.control.Button
        ChnphngphptmnghimButtonGroup    matlab.ui.container.ButtonGroup
        tieptuyen                       matlab.ui.control.RadioButton
        lap                             matlab.ui.control.RadioButton
        chiadoi                         matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
        NoisuyTab_2                     matlab.ui.container.Tab
        KtquButton_2                    matlab.ui.control.Button
        Nhapgiatricannoisuy_2           matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_2           matlab.ui.control.Label
        Ketquadathucnoisu_2             matlab.ui.control.EditField
        KtquathcnisuyLabel_2            matlab.ui.control.Label
        ChonphuongphapnoisuyButton_2    matlab.ui.container.ButtonGroup
        LagrangeButton_2                matlab.ui.control.ToggleButton
        NewtonButton_2                  matlab.ui.control.ToggleButton
        Nhapdulieuy_2                   matlab.ui.control.NumericEditField
        NhpdliuyLabel_5                 matlab.ui.control.Label
        Nhapdulieux_2                   matlab.ui.control.NumericEditField
        NhpdliuxLabel_5                 matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        HoiquyTab_2                     matlab.ui.container.Tab
        hoiQuyPredictorEditField_2      matlab.ui.control.NumericEditField
        NhpgitrcndonLabel_2             matlab.ui.control.Label
        hoiQuyDuLieuYField_2            matlab.ui.control.EditField
        NhpmngYLabel_2                  matlab.ui.control.Label
        hoiQuyDuLieuXField_2            matlab.ui.control.EditField
        NhpmngXLabel_2                  matlab.ui.control.Label
        hoiQuyPredictionResultLabel_2   matlab.ui.control.Label
        hoiQuyErrorMessage_2            matlab.ui.control.Label
        hoiQuyPredictionResultTitle_2   matlab.ui.control.Label
        hoiQuyPredictionButton_2        matlab.ui.control.Button
        phuongTrinhHoiQuyLabel_2        matlab.ui.control.Label
        phuongTrinhHoiQuyTitle_2        matlab.ui.control.Label
        hoiQuyDrawerButton_2            matlab.ui.control.Button
        hoiQuyMethodButtonGroup_2       matlab.ui.container.ButtonGroup
        exponentialOptionButton_2       matlab.ui.control.RadioButton
        linearOptionButton_2            matlab.ui.control.RadioButton
        hoiquyUIAxes_2                  matlab.ui.control.UIAxes
        DaohamTab_2                     matlab.ui.container.Tab
        Choncahtinh_DaoHam_ButtonGroup_2  matlab.ui.container.ButtonGroup
        Hamso_DaoHam_Button_2           matlab.ui.control.RadioButton
        MangXY_DaoHam_Button_2          matlab.ui.control.RadioButton
        Chongiatrisaiso_DaoHam_ButtonGroup_2  matlab.ui.container.ButtonGroup
        Oh2_DaoHam_Button_2             matlab.ui.control.RadioButton
        Oh_DaoHam_Button_2              matlab.ui.control.RadioButton
        Giatricantinh_EditField_DaoHam_2  matlab.ui.control.EditField
        NhpgitrcntnhohmEditFieldLabel_2  matlab.ui.control.Label
        YData_DaoHam_2                  matlab.ui.control.EditField
        NhpyEditFieldLabel_2            matlab.ui.control.Label
        XData_DaoHam_2                  matlab.ui.control.EditField
        NhpxEditFieldLabel_2            matlab.ui.control.Label
        Tinh_Button_DaoHam_2            matlab.ui.control.Button
        Ketqua_Label_DaoHam_2           matlab.ui.control.Label
        KtquLabel_2                     matlab.ui.control.Label
        ChnphngphpButtonGroup_2         matlab.ui.container.ButtonGroup
        TrungTam_Button_DaoHam_2        matlab.ui.control.RadioButton
        Lui_Button_DaoHam_2             matlab.ui.control.RadioButton
        Tien_Button_DaoHam_2            matlab.ui.control.RadioButton
        Buoch_DaoHam_2                  matlab.ui.control.EditField
        NhpbchLabel_2                   matlab.ui.control.Label
        Hamso_DaoHam_2                  matlab.ui.control.EditField
        NhphmsEditFieldLabel_3          matlab.ui.control.Label
        bonustext_3                     matlab.ui.control.Label
        TichPhanTab_2                   matlab.ui.container.Tab
        Ydata_2                         matlab.ui.control.EditField
        NhpdliuYEditFieldLabel_2        matlab.ui.control.Label
        Xdata_2                         matlab.ui.control.EditField
        NhpdliuXEditFieldLabel_2        matlab.ui.control.Label
        KetQuaLabel_2                   matlab.ui.control.Label
        KetQua_2                        matlab.ui.control.Label
        ChonInput_2                     matlab.ui.container.ButtonGroup
        NhapHamSo_2                     matlab.ui.control.RadioButton
        VectorX_VectorY_2               matlab.ui.control.RadioButton
        CalcIntegral_2                  matlab.ui.control.Button
        Methods_2                       matlab.ui.container.ButtonGroup
        Simpson38_2                     matlab.ui.control.RadioButton
        Simpson13_2                     matlab.ui.control.RadioButton
        HinhThang_2                     matlab.ui.control.RadioButton
        CanA_2                          matlab.ui.control.NumericEditField
        CndiaEditFieldLabel_2           matlab.ui.control.Label
        CanB_2                          matlab.ui.control.NumericEditField
        CntrnbEditFieldLabel_2          matlab.ui.control.Label
        dataN_2                         matlab.ui.control.NumericEditField
        NhpNLabel_2                     matlab.ui.control.Label
        Hamso_2                         matlab.ui.control.EditField
        NhphmsEditFieldLabel_4          matlab.ui.control.Label
        IntroTab_2                      matlab.ui.container.Tab
        LOP_2                           matlab.ui.control.EditField
        LPEditFieldLabel_2              matlab.ui.control.Label
        CHUCVU_2                        matlab.ui.control.EditField
        CHCVEditFieldLabel_2            matlab.ui.control.Label
        NGAYSINH_2                      matlab.ui.control.EditField
        NGYSINHEditFieldLabel_2         matlab.ui.control.Label
        NHIEMVU_2                       matlab.ui.control.EditField
        NHIMVEditFieldLabel_2           matlab.ui.control.Label
        MSSV_2                          matlab.ui.control.EditField
        MSSVLabel_2                     matlab.ui.control.Label
        GIITHIUNHMLabel_2               matlab.ui.control.Label
        Tree_2                          matlab.ui.container.Tree
        THANHVIEN_2                     matlab.ui.container.TreeNode
        DinhDungNode_2                  matlab.ui.container.TreeNode
        VanHyNode_2                     matlab.ui.container.TreeNode
        QuangDungNode_2                 matlab.ui.container.TreeNode
        NguynHuyDuNode_2                matlab.ui.container.TreeNode
        BaoQuocNode_2                   matlab.ui.container.TreeNode
        MinhQuangNode_2                 matlab.ui.container.TreeNode
        TabGroup                        matlab.ui.container.TabGroup
        NghiemTab                       matlab.ui.container.Tab
        NoisuyTab                       matlab.ui.container.Tab
        KtquButton                      matlab.ui.control.Button
        Nhapgiatricannoisuy             matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel             matlab.ui.control.Label
        Ketquadathucnoisu               matlab.ui.control.EditField
        KtquathcnisuyLabel              matlab.ui.control.Label
        ChonphuongphapnoisuyButton      matlab.ui.container.ButtonGroup
        LagrangeButton                  matlab.ui.control.ToggleButton
        NewtonButton                    matlab.ui.control.ToggleButton
        Nhapdulieuy                     matlab.ui.control.NumericEditField
        NhpdliuyLabel_4                 matlab.ui.control.Label
        Nhapdulieux                     matlab.ui.control.NumericEditField
        NhpdliuxLabel_4                 matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        HoiquyTab                       matlab.ui.container.Tab
        hoiQuyPredictorEditField        matlab.ui.control.NumericEditField
        NhpgitrcndonLabel               matlab.ui.control.Label
        hoiQuyDuLieuYField              matlab.ui.control.EditField
        NhpmngYLabel                    matlab.ui.control.Label
        hoiQuyDuLieuXField              matlab.ui.control.EditField
        NhpmngXLabel                    matlab.ui.control.Label
        hoiQuyPredictionResultLabel     matlab.ui.control.Label
        hoiQuyErrorMessage              matlab.ui.control.Label
        hoiQuyPredictionResultTitle     matlab.ui.control.Label
        hoiQuyPredictionButton          matlab.ui.control.Button
        phuongTrinhHoiQuyLabel          matlab.ui.control.Label
        phuongTrinhHoiQuyTitle          matlab.ui.control.Label
        hoiQuyDrawerButton              matlab.ui.control.Button
        hoiQuyMethodButtonGroup         matlab.ui.container.ButtonGroup
        exponentialOptionButton         matlab.ui.control.RadioButton
        linearOptionButton              matlab.ui.control.RadioButton
        hoiquyUIAxes                    matlab.ui.control.UIAxes
        DaohamTab                       matlab.ui.container.Tab
        Choncahtinh_DaoHam_ButtonGroup  matlab.ui.container.ButtonGroup
        Hamso_DaoHam_Button             matlab.ui.control.RadioButton
        MangXY_DaoHam_Button            matlab.ui.control.RadioButton
        Chongiatrisaiso_DaoHam_ButtonGroup  matlab.ui.container.ButtonGroup
        Oh2_DaoHam_Button               matlab.ui.control.RadioButton
        Oh_DaoHam_Button                matlab.ui.control.RadioButton
        Giatricantinh_EditField_DaoHam  matlab.ui.control.EditField
        NhpgitrcntnhohmEditFieldLabel   matlab.ui.control.Label
        YData_DaoHam                    matlab.ui.control.EditField
        NhpyEditFieldLabel              matlab.ui.control.Label
        XData_DaoHam                    matlab.ui.control.EditField
        NhpxEditFieldLabel              matlab.ui.control.Label
        Tinh_Button_DaoHam              matlab.ui.control.Button
        Ketqua_Label_DaoHam             matlab.ui.control.Label
        KtquLabel                       matlab.ui.control.Label
        ChnphngphpButtonGroup           matlab.ui.container.ButtonGroup
        TrungTam_Button_DaoHam          matlab.ui.control.RadioButton
        Lui_Button_DaoHam               matlab.ui.control.RadioButton
        Tien_Button_DaoHam              matlab.ui.control.RadioButton
        Buoch_DaoHam                    matlab.ui.control.EditField
        NhpbchLabel                     matlab.ui.control.Label
        Hamso_DaoHam                    matlab.ui.control.EditField
        NhphmsEditFieldLabel_2          matlab.ui.control.Label
        bonustext_2                     matlab.ui.control.Label
        TichPhanTab                     matlab.ui.container.Tab
        Ydata                           matlab.ui.control.EditField
        NhpdliuYEditFieldLabel          matlab.ui.control.Label
        Xdata                           matlab.ui.control.EditField
        NhpdliuXEditFieldLabel          matlab.ui.control.Label
        KetQuaLabel                     matlab.ui.control.Label
        KetQua                          matlab.ui.control.Label
        ChonInput                       matlab.ui.container.ButtonGroup
        NhapHamSo                       matlab.ui.control.RadioButton
        VectorX_VectorY                 matlab.ui.control.RadioButton
        CalcIntegral                    matlab.ui.control.Button
        Methods                         matlab.ui.container.ButtonGroup
        Simpson38                       matlab.ui.control.RadioButton
        Simpson13                       matlab.ui.control.RadioButton
        HinhThang                       matlab.ui.control.RadioButton
        CanA                            matlab.ui.control.NumericEditField
        CndiaEditFieldLabel             matlab.ui.control.Label
        CanB                            matlab.ui.control.NumericEditField
        CntrnbEditFieldLabel            matlab.ui.control.Label
        dataN                           matlab.ui.control.NumericEditField
        NhpNLabel                       matlab.ui.control.Label
        Hamso                           matlab.ui.control.EditField
        NhphmsEditFieldLabel            matlab.ui.control.Label
        IntroTab                        matlab.ui.container.Tab
        LOP                             matlab.ui.control.EditField
        LPEditFieldLabel                matlab.ui.control.Label
        CHUCVU                          matlab.ui.control.EditField
        CHCVEditFieldLabel              matlab.ui.control.Label
        NGAYSINH                        matlab.ui.control.EditField
        NGYSINHEditFieldLabel           matlab.ui.control.Label
        NHIEMVU                         matlab.ui.control.EditField
        NHIMVEditFieldLabel             matlab.ui.control.Label
        MSSV                            matlab.ui.control.EditField
        MSSVLabel                       matlab.ui.control.Label
        GIITHIUNHMLabel                 matlab.ui.control.Label
        Tree                            matlab.ui.container.Tree
        THANHVIEN                       matlab.ui.container.TreeNode
        DinhDungNode                    matlab.ui.container.TreeNode
        VanHyNode                       matlab.ui.container.TreeNode
        QuangDungNode                   matlab.ui.container.TreeNode
        NguynHuyDuNode                  matlab.ui.container.TreeNode
        BaoQuocNode                     matlab.ui.container.TreeNode
        MinhQuangNode                   matlab.ui.container.TreeNode
    end

    
    methods (Access = private)
        %---------------Tich phan tab ------------------------%
        
             % Hinh Thang function
             function y = hinhThang(app,fx,a,b,N)
                        h = (b -a)/N;
                        s1 = (fx(a) + fx(b));
                        s2 = 0;
                        for i = 1:N-1
                            s2 = s2 + fx(a + i*h);
                        end
                        y = h/2*(s1 + 2*s2);
    
             end
            
             % Simpson13 function
            function y = tichphanSimpson13(app,fx,a,b,N)
                        h = (b - a)/N;
                        s1 = (fx(a) + fx(b));
                        so = 0; %sum of odd
                        se = 0; %sum of even
                        if(rem(N, 2) == 1)
                            y = 0;
                            return;
                        else
                            for i=1:N-1
                                if(rem(i,2)==1) 
                                    so = so + fx(a + i*h);
                                else
                                    se = se + fx(a + i*h);
                                end
                            end
                        end
                        
                        y = h/3*(s1 + 4*so + 2*se);
            end
                  
    
            % A function to erase '.' character in fx
            function results = Change_Fx(app, fx)
                   results = strrep(fx,'*','.*');
                   results = strrep(results,'^','.^');
                   results = strrep(results,'/','./');
                
            end
      
        %Lagrange Function
        function result = Lagrange(app,xa, ya, x)
            n = length(xa);
            sum = 0;
            for i = 1:n
                product = ya(i);
                for j = 1:n
                    if i ~= j
                        product = product*(x - xa(j))/(xa(i) - xa(j));
                    end
                end
                sum = sum + product;
            end
            result = sum;
        end
    

        %Return lagrange 
        function f = Dathuc_Lagrange(app,xa, ya)
            syms x;
            f(x) = simplify(Lagrange(app,xa, ya, x));
        end

 
        % Simpson38 function
        function y = tichphanSimpson38(app,fx,a,b,N)
             h = (b - a)/N;
             s1 = (fx(a) + fx(b));
             s2 = 0; 
             s3 = 0;
             if(rem(N, 3) ~= 0)
                 y = 0;
                 return;
             else
                 for i=1:N-1
                    if(rem(i, 3) == 0)
                        s3 = s3 + fx(a + i*h);
                    else
                        s2 = s2 + fx(a + i*h);
                    end
                end
             end
             
            y = (3 * h / 8) * (s1 + 3 * s2 + 2 * s3);
           
         end
        %-------------------Tich phan tab---------------------------%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function ChiadoiButtonPushed(app, event)
                syms x;
                f = str2func(['@(x)',app.NhapPT.Value]);
                app.Solanlap.Value = 0;    %khai báo biến đếm số lần lặp
                while(1)
                    app.Solanlap.Value = app.Solanlap.Value + 1;    %mỗi lần lặp lại biến solanlap +1
                    c = (app.a.Value + app.b.Value) / 2;              %tính c bằng (a+b)/2
                    if(f(c) * f(app.a.Value) < 0)         %nếu f(c)*f(a)<0 có nghĩa khoảng (a,c) có nghiệm
                        app.b.Value = c;                %thay b=c
                    else
                        app.a.Value = c;                %ngược lại nếu f(c)*f(a)>0 tức trong khoảng (a,c) không có nghiệm, nghiệm nằm trong khoảng (b,c) 
                    end
                    e = abs(app.b.Value - app.a.Value);             %tính trị tuyệt đối b-1
                    if(e <= app.saiso.Value)             %nếu e< sai số thì thoát lặp
                        break
                    end
                end
                app.Ketqua.Value = app.a.Value;               %nghiệm của phương trình bắng a

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
           
            if(selectedNodes==app.MinhQuangNode)
                app.MSSV.Value = '21200339';
                app.NGAYSINH.Value = '02/01/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Giới thiệu nhóm';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end

            if (selectedNodes==app.BaoQuocNode)
                app.MSSV.Value = '21200344';
                app.NGAYSINH.Value = '10/10/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Tích phân';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end

            if (selectedNodes==app.NguynHuyDuNode)
                app.MSSV.Value = '21200279';
                app.NGAYSINH.Value = '20/08/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Đạo hàm';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thư ký';
            end
            if (selectedNodes==app.QuangDungNode)
                app.MSSV.Value = '21200282';
                app.NGAYSINH.Value = '18/12/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Hồi quy';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end
            if (selectedNodes==app.VanHyNode)
                app.MSSV.Value = '21200296';
                app.NGAYSINH.Value = '03/11/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Nội suy';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end
            if (selectedNodes==app.DinhDungNode)
                app.MSSV.Value = '21200280';
                app.NGAYSINH.Value = '16/06/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Tìm nghiệm';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Trưởng nhóm';
            end 
        end

        % Value changed function: MSSV
        function MSSVValueChanged(app, event)
            value = app.MSSV.Value;
            
        end

        % Value changed function: NGAYSINH
        function NGAYSINHValueChanged(app, event)
            value = app.NGAYSINH.Value;
            
        end

        % Value changed function: LOP
        function LOPValueChanged(app, event)
            value = app.LOP.Value;
            
        end

        % Value changed function: CHUCVU
        function CHUCVUValueChanged(app, event)
            value = app.CHUCVU.Value;
            
        end

        % Value changed function: NHIEMVU
        function NHIEMVUValueChanged(app, event)
            value = app.NHIEMVU.Value;
            
        end

        % Button pushed function: CalcIntegral
        function CalcIntegralButtonPushed(app, event)
                
                a = app.CanA.Value;
                b = app.CanB.Value;
                N = app.dataN.Value;   
                fx = app.Hamso.Value;
                fx_new = Change_Fx(app,fx);
                syms x;
                fx_hand_new = str2func(['@(x)', fx_new]);
                
                ArrayX = str2num(app.Xdata.Value);
                ArrayY = str2num(app.Ydata.Value);
                fxFromArray = Dathuc_Lagrange(app,ArrayX, ArrayY);
                fx_handle = matlabFunction(fxFromArray);
%                 fx_new1 = func2str(fx_handle);
%                 fx_hand_new1 = Change_Fx(fx_new1);
%                  
                
               if(app.HinhThang.Value == 1 && app.NhapHamSo.Value == 1)
             
                    HTRes = hinhThang(app,fx_hand_new, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', HTRes);
                    
               elseif(app.Simpson13.Value == 1 && app.NhapHamSo.Value == 1)
                   
                     SS13Res = tichphanSimpson13(app,fx_hand_new, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', SS13Res);
               elseif(app.Simpson38.Value == 1 && app.NhapHamSo.Value == 1)
                   SS38Res = tichphanSimpson38(app,fx_hand_new, a, b, N);
                   app.KetQua.Text = sprintf('%.10f', SS38Res);
           
               end
               
               if(app.HinhThang.Value == 1 && app.VectorX_VectorY.Value == 1)
             
                    HTRes = hinhThang(app,fx_handle, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', HTRes);
                    
               elseif(app.Simpson13.Value == 1 && app.VectorX_VectorY.Value == 1)
                   
                     SS13Res = tichphanSimpson13(app,fx_handle, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', SS13Res);
               elseif(app.Simpson38.Value == 1 && app.VectorX_VectorY.Value == 1)
                   SS38Res = tichphanSimpson38(app,fx_handle, a, b, N);
                   app.KetQua.Text = sprintf('%.10f', SS38Res);
           
               end
        end

        % Selection changed function: ChonInput
        function ChonInputSelectionChanged(app, event)
            selectedButton = app.ChonInput.SelectedObject;
            if(selectedButton == app.VectorX_VectorY)
                app.Hamso.Visible = "off";
                app.Ydata.Visible = "on";
                app.Xdata.Visible = "on";
            else
                app.Hamso.Visible = "on";
                app.Ydata.Visible = "off";
                app.Xdata.Visible = "off";
            end
        end

        % Button pushed function: Tinh_Button_DaoHam
        function Tinh_Button_DaoHamPushed(app, event)
            mangX = str2num(app.XData_DaoHam.Value);
            mangY = str2num(app.YData_DaoHam.Value);
            f = app.Hamso_DaoHam.Value;
            f = str2func(['@(x)', f]);
            h = str2num(app.Buoch_DaoHam.Value);
            giatricantinh = str2num(app.Giatricantinh_EditField_DaoHam.Value);

            if app.Tien_Button_DaoHam.Value == 1
                pp = "Tiến";
            end
            if app.Lui_Button_DaoHam.Value == 1
                pp = "Lùi";
            end
            if app.TrungTam_Button_DaoHam.Value == 1
                pp = "Trung tâm";
            end

            if app.Oh2_DaoHam_Button.Value == 1
                saiso_daoham = "Oh2";
            end
            if app.Oh_DaoHam_Button.Value == 1
                saiso_daoham = "Oh";
            end

            if app.Hamso_DaoHam_Button.Value == 1
                df = xapxidaoham_hamso(f,h,pp,saiso_daoham,giatricantinh);
                app.Ketqua_Label_DaoHam.Text = formattedDisplayText(df,"NumericFormat","long");
            end

            if app.MangXY_DaoHam_Button.Value == 1
                df = tinhxapxidaoham(mangX,mangY,giatricantinh,pp);
                app.Ketqua_Label_DaoHam.Text = num2str(df);
            end
            
        end

        % Callback function
        function Chongiatrisaiso_DaoHam_ButtonGroupSelectionChanged(app, event)
            
        end

        % Selection changed function: Choncahtinh_DaoHam_ButtonGroup
        function Choncahtinh_DaoHam_ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Choncahtinh_DaoHam_ButtonGroup.SelectedObject;
            selectedButton = app.MangXY_DaoHam_Button;
            if app.MangXY_DaoHam_Button.Value == 1
                app.Hamso_DaoHam.Visible = "off";
                app.Buoch_DaoHam.Visible = "off";
                app.XData_DaoHam.Visible = "on";
                app.YData_DaoHam.Visible = "on";
            else
                app.XData_DaoHam.Visible = "off";
                app.YData_DaoHam.Visible = "off";
                app.Hamso_DaoHam.Visible = "on";
                app.Buoch_DaoHam.Visible = "on";
            end
        end

        % Button pushed function: hoiQuyPredictionButton
        function hoiQuyPredictionButtonPushed(app, event)
              try
                x = str2num(app.hoiQuyDuLieuXField.Value);
                y = str2num(app.hoiQuyDuLieuYField.Value);
                
                
                option = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (option == app.linearOptionButton.Value)
                    fx = linearRegression(x, y);
                elseif (option == app.exponentialOptionButton.Value)
                    fx = exponentialRegression(x, y);           
                end
                
                x = app.hoiQuyPredictorEditField.Value;
                app.hoiQuyPredictionResultLabel.Text = num2str(fx(x));
                app.hoiQuyErrorMessage.Text = '';
            catch e
                app.hoiQuyErrorMessage.Text = e.message;
                disp(e);
            end
        end

        % Button pushed function: hoiQuyDrawerButton
        function hoiQuyDrawerButtonPushed(app, event)
            try
                x = str2num(app.hoiQuyDuLieuXField.Value);
                y = str2num(app.hoiQuyDuLieuYField.Value);
                
                pp = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (pp == app.linearOptionButton.Value)
                    fx = linearRegression(x, y);
                else
                    fx = exponentialRegression(x,y);
                end
                %  n = min(a) : 0.1 : max(a);
                % plot(app.hoiquyUIAxes, n, fx(n), 'b', 'LineWidth', 1);
                fplot(app.hoiquyUIAxes,fx);
                grid(app.hoiquyUIAxes, 'on');
                axis(app.hoiquyUIAxes);
                   s = func2str(fx);
                app.phuongTrinhHoiQuyLabel.Text = s(5:end);
                app.hoiQuyErrorMessage.Text = '';
                app.phuongTrinhHoiQuyLabel.Visible = true;
                app.phuongTrinhHoiQuyTitle.Visible = true;
                app.hoiQuyPredictionResultLabel.Visible = true;
                app.hoiQuyPredictionResultTitle.Visible = true;
            catch e
                app.hoiQuyErrorMessage.Text = e.message;

                app.phuongTrinhHoiQuyLabel.Visible = false;
                app.phuongTrinhHoiQuyTitle.Visible = false;
                app.hoiQuyPredictionResultLabel.Visible = false;
                app.hoiQuyPredictionResultTitle.Visible = false;
            end
                    
        end

        % Button pushed function: TnhvvButton
        function TnhvvButtonPushed(app, event)
             syms x;
            f = str2func(['@(x)',app.nhappt.Value]);
            khoangtinh = app.khoang.Value; 
            khoangtinh = regexprep(khoangtinh, '[\[\]]', ''); % loại bỏ các ký tự [ và ]
            giatri = strsplit(khoangtinh, ';'); % tách chuỗi thành hai phần tử dựa trên dấu ;
            a = str2double(giatri{1}); % chuyển đổi chuỗi sang số
            b = str2double(giatri{2}); % chuyển đổi chuỗi sang số
            saiso=app.saiso.Value;
            x1=linspace(a,b,1000);
            y=arrayfun(f,x1)%tính toán giá trị hàm f cho mỗi giá trị x1
            plot(app.UIAxes3,x1,y);
            if(app.chiadoi.Value==1)
                 fd = str2func(['@(x)' ,char(diff(f(x)))]);
                 if f(a) * f(b) > 0
                    app.ketqua.Value='f(a) và f(b) phải trái dấu kiểm tra khoảng nghiệm';
                    app.solanlap.Value=0;
                 return        
                 end
               
                 app.solanlap.Value=0;
                 while (b-a)/2 > saiso
                        c = (a+b)/2;
                        if f(c)==0
                            break
                        elseif f(a)*f(c)<0
                            b=c;
                        else
                            a=c;
                        end
                        app.solanlap.Value=app.solanlap.Value+ 1;
                 end
                 app.ketqua.Value=num2str((a+b)/2);
            end
            if(app.tieptuyen.Value==1)
            fd1 = str2func(['@(x)' ,char(diff(f(x)))]);
            fd2 = str2func(['@(x)' ,char(diff(diff(f(x))))]);
            app.solanlap.Value=0;         %khai báo biến đếm số lần lặp
            x0=a;
            nostop = 1;
            if f(a) * f(b) > 0
                    app.ketqua.Value='f(a) và f(b) phải trái dấu kiểm tra khoảng nghiệm';
                 return
            end
          
  
        if (nostop)
           while f(x0)*fd2(x0)<0    
                x0 = (x0 + b) / 2;
            end 
            app.solanlap.Value = 0;
            while (1)
                app.solanlap.Value = app.solanlap.Value + 1;
                x1 = x0 - f(x0)/fd1(x0);
            if(abs(x1-x0)<app.saiso.Value)
                break
            end
            x0=x1;
           end
            app.ketqua.Value = num2str(x1);
        end
      end
            if(app.lap.Value==1)
            lap_max=1000
            app.solanlap.Value=0;     %khai báo biến đếm số lần lặp 
            i=2;            %thiết lập giá trị ban đầu của i=2
            z(1)=a;         %gán x(1)=a 
            z(2)=b;         %gán x(2)=b
            while(1)
                if(app.solanlap.Value>lap_max)
                    return
                end
                app.solanlap.Value=app.solanlap.Value+1;    %biến lặp tăng 1 sau mỗi vòng lặp
                z(i)=f(z(i-1));        %tính giá trị x(i) theo hàm lặp với giá trị x(i-1) trước đó
                if abs(z(i)-z(i-1))<=app.saiso.Value       %hiệu tuyệt đối giua gia trị hiện tại và giá trị trước đó nếu nhỏ hơn sai số thì thoát lặp 
                break;
                end
                i=i+1;    %tăng i để tính x(i) mới       
            end
            app.ketqua.Value=num2str(z(i));  %gán nghiệm chính là x(i)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội Suy';
            app.UIAxes_2 = uiaxes(app.NoisuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số nội suy và dữ liệu')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.NhpdliuxLabel_4 = uilabel(app.NoisuyTab);
            app.NhpdliuxLabel_4.Text = 'Nhập dữ liệu x';
            app.Nhapdulieux = uieditfield(app.NoisuyTab, 'numeric');
            app.NhpdliuyLabel_4 = uilabel(app.NoisuyTab);
            app.NhpdliuyLabel_4.Text = 'Nhập dữ liệu y';
            app.Nhapdulieuy = uieditfield(app.NoisuyTab, 'numeric');
            app.ChonphuongphapnoisuyButton = uibuttongroup(app.NoisuyTab);
            app.ChonphuongphapnoisuyButton.Title = 'Chọn phương pháp nội suy';
            app.NewtonButton = uitogglebutton(app.ChonphuongphapnoisuyButton);
            app.NewtonButton.Text = 'Newton';
            app.NewtonButton.Value = true;
            app.LagrangeButton = uitogglebutton(app.ChonphuongphapnoisuyButton);
            app.LagrangeButton.Text = 'Lagrange';
            app.KtquathcnisuyLabel = uilabel(app.NoisuyTab);
            app.KtquathcnisuyLabel.Text = 'Kết quả đa thức nội suy';
            app.Ketquadathucnoisu = uieditfield(app.NoisuyTab, 'text');
            app.NhpgitrcnnisuyLabel = uilabel(app.NoisuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy';
            app.Nhapgiatricannoisuy = uieditfield(app.NoisuyTab, 'numeric');
            app.KtquButton = uibutton(app.NoisuyTab, 'push');
            app.KtquButton.Text = 'Kết quả';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi Quy';
            app.hoiquyUIAxes = uiaxes(app.HoiquyTab);
            title(app.hoiquyUIAxes, 'Đồ thị hồi quy')
            xlabel(app.hoiquyUIAxes, 'X')
            ylabel(app.hoiquyUIAxes, 'Y')
            app.hoiQuyMethodButtonGroup = uibuttongroup(app.HoiquyTab);
            app.hoiQuyMethodButtonGroup.Title = 'Chọn phương pháp hồi quy';
            app.linearOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.linearOptionButton.Text = 'Tuyến tính';
            app.linearOptionButton.Value = true;
            app.exponentialOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.exponentialOptionButton.Text = 'Hàm mũ';
            app.hoiQuyDrawerButton = uibutton(app.HoiquyTab, 'push');
            app.hoiQuyDrawerButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyDrawerButtonPushed, true);
            app.hoiQuyDrawerButton.Text = 'Tìm phương trình';
            app.phuongTrinhHoiQuyTitle = uilabel(app.HoiquyTab);
            app.phuongTrinhHoiQuyTitle.Text = 'Kết quả phương trình hồi quy';
            app.phuongTrinhHoiQuyLabel = uilabel(app.HoiquyTab);
            app.phuongTrinhHoiQuyLabel.Text = 'Kết quả phương trình hồi quy';
            app.hoiQuyPredictionButton = uibutton(app.HoiquyTab, 'push');
            app.hoiQuyPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyPredictionButtonPushed, true);
            app.hoiQuyPredictionResultTitle = uilabel(app.HoiquyTab);
            app.hoiQuyPredictionResultTitle.Text = 'Kết quả dự đoán';
            app.hoiQuyErrorMessage = uilabel(app.HoiquyTab);
            app.hoiQuyPredictionResultLabel = uilabel(app.HoiquyTab);
            app.hoiQuyPredictionResultLabel.Text = '0';
            app.NhpmngXLabel = uilabel(app.HoiquyTab);
            app.NhpmngXLabel.Text = 'Nhập mảng X';
            app.hoiQuyDuLieuXField = uieditfield(app.HoiquyTab, 'text');
            app.hoiQuyDuLieuXField.Value = '[1 2 3 4 5 6 7 8 9]';
            app.NhpmngYLabel = uilabel(app.HoiquyTab);
            app.NhpmngYLabel.Text = 'Nhập mảng Y';
            app.hoiQuyDuLieuYField = uieditfield(app.HoiquyTab, 'text');
            app.hoiQuyDuLieuYField.Value = '[1 1.5 2 3 4 5 8 10 13]';
            app.NhpgitrcndonLabel = uilabel(app.HoiquyTab);
            app.NhpgitrcndonLabel.Text = 'Nhập giá trị cần dự đoán';
            app.hoiQuyPredictorEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.hoiQuyPredictorEditField.Value = 2;
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo Hàm';
            app.bonustext_2 = uilabel(app.DaohamTab);
            app.bonustext_2.Text = 'Hoặc';
            app.NhphmsEditFieldLabel_2 = uilabel(app.DaohamTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Hamso_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.Hamso_DaoHam.Value = 'asin(x)';
            app.NhpbchLabel = uilabel(app.DaohamTab);
            app.NhpbchLabel.Text = 'Nhập bước h ';
            app.Buoch_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.Buoch_DaoHam.Value = '1*10^-5';
            app.ChnphngphpButtonGroup = uibuttongroup(app.DaohamTab);
            app.ChnphngphpButtonGroup.Title = 'Chọn phương pháp ';
            app.Tien_Button_DaoHam = uiradiobutton(app.ChnphngphpButtonGroup);
            app.Tien_Button_DaoHam.Text = 'Xấp xỉ tiến ';
            app.Tien_Button_DaoHam.Value = true;
            app.Lui_Button_DaoHam = uiradiobutton(app.ChnphngphpButtonGroup);
            app.Lui_Button_DaoHam.Text = 'Xấp xỉ lùi ';
            app.TrungTam_Button_DaoHam = uiradiobutton(app.ChnphngphpButtonGroup);
            app.TrungTam_Button_DaoHam.Text = 'Xấp xỉ trung tâm ';
            app.KtquLabel = uilabel(app.DaohamTab);
            app.KtquLabel.Text = 'Kết quả ';
            app.Ketqua_Label_DaoHam = uilabel(app.DaohamTab);
            app.Ketqua_Label_DaoHam.Text = '0';
            app.Tinh_Button_DaoHam = uibutton(app.DaohamTab, 'push');
            app.Tinh_Button_DaoHam.ButtonPushedFcn = createCallbackFcn(app, @Tinh_Button_DaoHamPushed, true);
            app.Tinh_Button_DaoHam.Text = 'Tính ';
            app.NhpxEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpxEditFieldLabel.Text = 'Nhập x ';
            app.XData_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.XData_DaoHam.Value = '[0.1 0.3 0.5 0.7 0.9]';
            app.NhpyEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpyEditFieldLabel.Text = 'Nhập y ';
            app.YData_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.YData_DaoHam.Value = '[0.1002 0.3047 0.5236 0.7754 1.1198]';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính Đạo hàm ';
            app.Giatricantinh_EditField_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.Giatricantinh_EditField_DaoHam.Value = '0.7';
            app.Chongiatrisaiso_DaoHam_ButtonGroup = uibuttongroup(app.DaohamTab);
            app.Chongiatrisaiso_DaoHam_ButtonGroup.Title = 'Chọn giá trị sai số';
            app.Oh_DaoHam_Button = uiradiobutton(app.Chongiatrisaiso_DaoHam_ButtonGroup);
            app.Oh_DaoHam_Button.Text = 'O(h)';
            app.Oh_DaoHam_Button.Value = true;
            app.Oh2_DaoHam_Button = uiradiobutton(app.Chongiatrisaiso_DaoHam_ButtonGroup);
            app.Oh2_DaoHam_Button.Text = 'O(h^2)';
            app.Choncahtinh_DaoHam_ButtonGroup = uibuttongroup(app.DaohamTab);
            app.Choncahtinh_DaoHam_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Choncahtinh_DaoHam_ButtonGroupSelectionChanged, true);
            app.Choncahtinh_DaoHam_ButtonGroup.Title = 'Chọn dữ liệu đầu vào';
            app.MangXY_DaoHam_Button = uiradiobutton(app.Choncahtinh_DaoHam_ButtonGroup);
            app.MangXY_DaoHam_Button.Text = 'Mảng X, Y';
            app.MangXY_DaoHam_Button.Value = true;
            app.Hamso_DaoHam_Button = uiradiobutton(app.Choncahtinh_DaoHam_ButtonGroup);
            app.Hamso_DaoHam_Button.Text = 'Hàm số';
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích Phân';
            app.NhphmsEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Hamso = uieditfield(app.TichPhanTab, 'text');
            app.Hamso.Value = 'x^3*sin(x) + x*cos(x)';
            app.NhpNLabel = uilabel(app.TichPhanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.dataN = uieditfield(app.TichPhanTab, 'numeric');
            app.dataN.Value = 10;
            app.CntrnbEditFieldLabel = uilabel(app.TichPhanTab);
            app.CntrnbEditFieldLabel.Text = 'Cận trên (b)';
            app.CanB = uieditfield(app.TichPhanTab, 'numeric');
            app.CanB.Value = 2;
            app.CndiaEditFieldLabel = uilabel(app.TichPhanTab);
            app.CndiaEditFieldLabel.Text = 'Cận dưới (a)';
            app.CanA = uieditfield(app.TichPhanTab, 'numeric');
            app.Methods = uibuttongroup(app.TichPhanTab);
            app.Methods.Title = 'Chọn phương pháp tính tích phân';
            app.HinhThang = uiradiobutton(app.Methods);
            app.HinhThang.Text = 'Hình Thang';
            app.HinhThang.Value = true;
            app.Simpson13 = uiradiobutton(app.Methods);
            app.Simpson13.Text = 'Simpson 1/3';
            app.Simpson38 = uiradiobutton(app.Methods);
            app.Simpson38.Text = 'Simpson 3/8';
            app.CalcIntegral = uibutton(app.TichPhanTab, 'push');
            app.CalcIntegral.ButtonPushedFcn = createCallbackFcn(app, @CalcIntegralButtonPushed, true);
            app.CalcIntegral.Text = 'Tính ';
            app.ChonInput = uibuttongroup(app.TichPhanTab);
            app.ChonInput.SelectionChangedFcn = createCallbackFcn(app, @ChonInputSelectionChanged, true);
            app.ChonInput.Title = 'Chọn dữ liệu đầu vào';
            app.VectorX_VectorY = uiradiobutton(app.ChonInput);
            app.VectorX_VectorY.Text = 'Mảng x, Mảng y';
            app.VectorX_VectorY.Value = true;
            app.NhapHamSo = uiradiobutton(app.ChonInput);
            app.NhapHamSo.Text = 'Hàm số';
            app.KetQua = uilabel(app.TichPhanTab);
            app.KetQua.Text = '0';
            app.KetQuaLabel = uilabel(app.TichPhanTab);
            app.KetQuaLabel.Text = 'Kết Quả';
            app.NhpdliuXEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpdliuXEditFieldLabel.Text = 'Nhập dữ liệu X';
            app.Xdata = uieditfield(app.TichPhanTab, 'text');
            app.Xdata.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuYEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpdliuYEditFieldLabel.Text = 'Nhập dữ liệu Y';
            app.Ydata = uieditfield(app.TichPhanTab, 'text');
            app.Ydata.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Giới thiệu nhóm';
            app.Tree = uitree(app.IntroTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.THANHVIEN = uitreenode(app.Tree);
            app.THANHVIEN.Text = '   THÀNH VIÊN';
            app.DinhDungNode = uitreenode(app.THANHVIEN);
            app.DinhDungNode.Text = 'Lê Đình Dũng';
            app.VanHyNode = uitreenode(app.THANHVIEN);
            app.VanHyNode.Text = 'Đặng Văn Hy';
            app.QuangDungNode = uitreenode(app.THANHVIEN);
            app.QuangDungNode.Text = 'Phạm Quang Dũng';
            app.NguynHuyDuNode = uitreenode(app.THANHVIEN);
            app.NguynHuyDuNode.Text = 'Nguyễn Huy Du';
            app.BaoQuocNode = uitreenode(app.THANHVIEN);
            app.BaoQuocNode.Text = 'Phạm Bảo Quốc';
            app.MinhQuangNode = uitreenode(app.THANHVIEN);
            app.MinhQuangNode.Text = 'Đỗ Minh Quang';
            app.GIITHIUNHMLabel = uilabel(app.IntroTab);
            app.GIITHIUNHMLabel.Text = 'GIỚI THIỆU NHÓM';
            app.MSSVLabel = uilabel(app.IntroTab);
            app.MSSVLabel.Text = 'MSSV ';
            app.MSSV = uieditfield(app.IntroTab, 'text');
            app.MSSV.ValueChangedFcn = createCallbackFcn(app, @MSSVValueChanged, true);
            app.MSSV.Editable = 'off';
            app.NHIMVEditFieldLabel = uilabel(app.IntroTab);
            app.NHIMVEditFieldLabel.Text = 'NHIỆM VỤ';
            app.NHIEMVU = uieditfield(app.IntroTab, 'text');
            app.NHIEMVU.ValueChangedFcn = createCallbackFcn(app, @NHIEMVUValueChanged, true);
            app.NHIEMVU.Editable = 'off';
            app.NGYSINHEditFieldLabel = uilabel(app.IntroTab);
            app.NGYSINHEditFieldLabel.Text = 'NGÀY SINH';
            app.NGAYSINH = uieditfield(app.IntroTab, 'text');
            app.NGAYSINH.ValueChangedFcn = createCallbackFcn(app, @NGAYSINHValueChanged, true);
            app.NGAYSINH.Editable = 'off';
            app.CHCVEditFieldLabel = uilabel(app.IntroTab);
            app.CHCVEditFieldLabel.Text = 'CHỨC VỤ';
            app.CHUCVU = uieditfield(app.IntroTab, 'text');
            app.CHUCVU.ValueChangedFcn = createCallbackFcn(app, @CHUCVUValueChanged, true);
            app.CHUCVU.Editable = 'off';
            app.LPEditFieldLabel = uilabel(app.IntroTab);
            app.LPEditFieldLabel.Text = 'LỚP';
            app.LOP = uieditfield(app.IntroTab, 'text');
            app.LOP.ValueChangedFcn = createCallbackFcn(app, @LOPValueChanged, true);
            app.LOP.Editable = 'off';
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.NghiemTab_2 = uitab(app.TabGroup_2);
            app.NghiemTab_2.Title = 'Nghiệm';
            app.UIAxes3 = uiaxes(app.NghiemTab_2);
            title(app.UIAxes3, 'Đồ thị hàm số')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ChnphngphptmnghimButtonGroup = uibuttongroup(app.NghiemTab_2);
            app.ChnphngphptmnghimButtonGroup.Title = 'Chọn phương pháp tìm nghiệm ';
            app.chiadoi = uiradiobutton(app.ChnphngphptmnghimButtonGroup);
            app.chiadoi.Text = 'Phương pháp chia đôi';
            app.chiadoi.Value = true;
            app.lap = uiradiobutton(app.ChnphngphptmnghimButtonGroup);
            app.lap.Text = 'Phương pháp lặp';
            app.tieptuyen = uiradiobutton(app.ChnphngphptmnghimButtonGroup);
            app.tieptuyen.Text = 'Phương pháp tiếp tuyến(Newton)';
            app.TnhvvButton = uibutton(app.NghiemTab_2, 'push');
            app.TnhvvButton.ButtonPushedFcn = createCallbackFcn(app, @TnhvvButtonPushed, true);
            app.TnhvvButton.Text = 'Tính và vẽ';
            app.LuKhongtnhphiccphpabLabel = uilabel(app.NghiemTab_2);
            app.LuKhongtnhphiccphpabLabel.Text = 'Lưu ý: Khoảng tính phải có cú pháp [a;b]';
            app.Khongtnhchc1nghimnLabel = uilabel(app.NghiemTab_2);
            app.Khongtnhchc1nghimnLabel.Text = 'Khoảng tính(chỉ có 1 nghiệm đơn)';
            app.khoang = uieditfield(app.NghiemTab_2, 'text');
            app.khoang.Value = '[-1;2]';
            app.KtquEditFieldLabel = uilabel(app.NghiemTab_2);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.ketqua = uieditfield(app.NghiemTab_2, 'text');
            app.nhappt = uieditfield(app.NghiemTab_2, 'text');
            app.nhappt.Value = 'x-sin(x)-0.25';
            app.NhpvohmhmlpLabel = uilabel(app.NghiemTab_2);
            app.NhpvohmhmlpLabel.Text = 'Nhập vào hàm/hàm lặp';
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab_2);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.solanlap = uieditfield(app.NghiemTab_2, 'numeric');
            app.SaisEditFieldLabel = uilabel(app.NghiemTab_2);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.saiso = uieditfield(app.NghiemTab_2, 'numeric');
            app.saiso.Value = 0.005;
            app.NoisuyTab_2 = uitab(app.TabGroup_2);
            app.NoisuyTab_2.Title = 'Nội Suy';
            app.UIAxes_3 = uiaxes(app.NoisuyTab_2);
            title(app.UIAxes_3, 'Đồ thị hàm số nội suy và dữ liệu')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.NhpdliuxLabel_5 = uilabel(app.NoisuyTab_2);
            app.NhpdliuxLabel_5.Text = 'Nhập dữ liệu x';
            app.Nhapdulieux_2 = uieditfield(app.NoisuyTab_2, 'numeric');
            app.NhpdliuyLabel_5 = uilabel(app.NoisuyTab_2);
            app.NhpdliuyLabel_5.Text = 'Nhập dữ liệu y';
            app.Nhapdulieuy_2 = uieditfield(app.NoisuyTab_2, 'numeric');
            app.ChonphuongphapnoisuyButton_2 = uibuttongroup(app.NoisuyTab_2);
            app.ChonphuongphapnoisuyButton_2.Title = 'Chọn phương pháp nội suy';
            app.NewtonButton_2 = uitogglebutton(app.ChonphuongphapnoisuyButton_2);
            app.NewtonButton_2.Text = 'Newton';
            app.NewtonButton_2.Value = true;
            app.LagrangeButton_2 = uitogglebutton(app.ChonphuongphapnoisuyButton_2);
            app.LagrangeButton_2.Text = 'Lagrange';
            app.KtquathcnisuyLabel_2 = uilabel(app.NoisuyTab_2);
            app.KtquathcnisuyLabel_2.Text = 'Kết quả đa thức nội suy';
            app.Ketquadathucnoisu_2 = uieditfield(app.NoisuyTab_2, 'text');
            app.NhpgitrcnnisuyLabel_2 = uilabel(app.NoisuyTab_2);
            app.NhpgitrcnnisuyLabel_2.Text = 'Nhập giá trị cần nội suy';
            app.Nhapgiatricannoisuy_2 = uieditfield(app.NoisuyTab_2, 'numeric');
            app.KtquButton_2 = uibutton(app.NoisuyTab_2, 'push');
            app.KtquButton_2.Text = 'Kết quả';
            app.HoiquyTab_2 = uitab(app.TabGroup_2);
            app.HoiquyTab_2.Title = 'Hồi Quy';
            app.hoiquyUIAxes_2 = uiaxes(app.HoiquyTab_2);
            title(app.hoiquyUIAxes_2, 'Đồ thị hồi quy')
            xlabel(app.hoiquyUIAxes_2, 'X')
            ylabel(app.hoiquyUIAxes_2, 'Y')
            app.hoiQuyMethodButtonGroup_2 = uibuttongroup(app.HoiquyTab_2);
            app.hoiQuyMethodButtonGroup_2.Title = 'Chọn phương pháp hồi quy';
            app.linearOptionButton_2 = uiradiobutton(app.hoiQuyMethodButtonGroup_2);
            app.linearOptionButton_2.Text = 'Tuyến tính';
            app.linearOptionButton_2.Value = true;
            app.exponentialOptionButton_2 = uiradiobutton(app.hoiQuyMethodButtonGroup_2);
            app.exponentialOptionButton_2.Text = 'Hàm mũ';
            app.hoiQuyDrawerButton_2 = uibutton(app.HoiquyTab_2, 'push');
            app.hoiQuyDrawerButton_2.Text = 'Tìm phương trình';
            app.phuongTrinhHoiQuyTitle_2 = uilabel(app.HoiquyTab_2);
            app.phuongTrinhHoiQuyTitle_2.Text = 'Kết quả phương trình hồi quy';
            app.phuongTrinhHoiQuyLabel_2 = uilabel(app.HoiquyTab_2);
            app.phuongTrinhHoiQuyLabel_2.Text = 'Kết quả phương trình hồi quy';
            app.hoiQuyPredictionButton_2 = uibutton(app.HoiquyTab_2, 'push');
            app.hoiQuyPredictionResultTitle_2 = uilabel(app.HoiquyTab_2);
            app.hoiQuyPredictionResultTitle_2.Text = 'Kết quả dự đoán';
            app.hoiQuyErrorMessage_2 = uilabel(app.HoiquyTab_2);
            app.hoiQuyPredictionResultLabel_2 = uilabel(app.HoiquyTab_2);
            app.hoiQuyPredictionResultLabel_2.Text = '0';
            app.NhpmngXLabel_2 = uilabel(app.HoiquyTab_2);
            app.NhpmngXLabel_2.Text = 'Nhập mảng X';
            app.hoiQuyDuLieuXField_2 = uieditfield(app.HoiquyTab_2, 'text');
            app.hoiQuyDuLieuXField_2.Value = '[1 2 3 4 5 6 7 8 9]';
            app.NhpmngYLabel_2 = uilabel(app.HoiquyTab_2);
            app.NhpmngYLabel_2.Text = 'Nhập mảng Y';
            app.hoiQuyDuLieuYField_2 = uieditfield(app.HoiquyTab_2, 'text');
            app.hoiQuyDuLieuYField_2.Value = '[1 1.5 2 3 4 5 8 10 13]';
            app.NhpgitrcndonLabel_2 = uilabel(app.HoiquyTab_2);
            app.NhpgitrcndonLabel_2.Text = 'Nhập giá trị cần dự đoán';
            app.hoiQuyPredictorEditField_2 = uieditfield(app.HoiquyTab_2, 'numeric');
            app.hoiQuyPredictorEditField_2.Value = 2;
            app.DaohamTab_2 = uitab(app.TabGroup_2);
            app.DaohamTab_2.Title = 'Đạo Hàm';
            app.bonustext_3 = uilabel(app.DaohamTab_2);
            app.bonustext_3.Text = 'Hoặc';
            app.NhphmsEditFieldLabel_3 = uilabel(app.DaohamTab_2);
            app.NhphmsEditFieldLabel_3.Text = 'Nhập hàm số';
            app.Hamso_DaoHam_2 = uieditfield(app.DaohamTab_2, 'text');
            app.Hamso_DaoHam_2.Value = 'asin(x)';
            app.NhpbchLabel_2 = uilabel(app.DaohamTab_2);
            app.NhpbchLabel_2.Text = 'Nhập bước h ';
            app.Buoch_DaoHam_2 = uieditfield(app.DaohamTab_2, 'text');
            app.Buoch_DaoHam_2.Value = '1*10^-5';
            app.ChnphngphpButtonGroup_2 = uibuttongroup(app.DaohamTab_2);
            app.ChnphngphpButtonGroup_2.Title = 'Chọn phương pháp ';
            app.Tien_Button_DaoHam_2 = uiradiobutton(app.ChnphngphpButtonGroup_2);
            app.Tien_Button_DaoHam_2.Text = 'Xấp xỉ tiến ';
            app.Tien_Button_DaoHam_2.Value = true;
            app.Lui_Button_DaoHam_2 = uiradiobutton(app.ChnphngphpButtonGroup_2);
            app.Lui_Button_DaoHam_2.Text = 'Xấp xỉ lùi ';
            app.TrungTam_Button_DaoHam_2 = uiradiobutton(app.ChnphngphpButtonGroup_2);
            app.TrungTam_Button_DaoHam_2.Text = 'Xấp xỉ trung tâm ';
            app.KtquLabel_2 = uilabel(app.DaohamTab_2);
            app.KtquLabel_2.Text = 'Kết quả ';
            app.Ketqua_Label_DaoHam_2 = uilabel(app.DaohamTab_2);
            app.Ketqua_Label_DaoHam_2.Text = '0';
            app.Tinh_Button_DaoHam_2 = uibutton(app.DaohamTab_2, 'push');
            app.Tinh_Button_DaoHam_2.Text = 'Tính ';
            app.NhpxEditFieldLabel_2 = uilabel(app.DaohamTab_2);
            app.NhpxEditFieldLabel_2.Text = 'Nhập x ';
            app.XData_DaoHam_2 = uieditfield(app.DaohamTab_2, 'text');
            app.XData_DaoHam_2.Value = '[0.1 0.3 0.5 0.7 0.9]';
            app.NhpyEditFieldLabel_2 = uilabel(app.DaohamTab_2);
            app.NhpyEditFieldLabel_2.Text = 'Nhập y ';
            app.YData_DaoHam_2 = uieditfield(app.DaohamTab_2, 'text');
            app.YData_DaoHam_2.Value = '[0.1002 0.3047 0.5236 0.7754 1.1198]';
            app.NhpgitrcntnhohmEditFieldLabel_2 = uilabel(app.DaohamTab_2);
            app.NhpgitrcntnhohmEditFieldLabel_2.Text = 'Nhập giá trị cần tính Đạo hàm ';
            app.Giatricantinh_EditField_DaoHam_2 = uieditfield(app.DaohamTab_2, 'text');
            app.Giatricantinh_EditField_DaoHam_2.Value = '0.7';
            app.Chongiatrisaiso_DaoHam_ButtonGroup_2 = uibuttongroup(app.DaohamTab_2);
            app.Chongiatrisaiso_DaoHam_ButtonGroup_2.Title = 'Chọn giá trị sai số';
            app.Oh_DaoHam_Button_2 = uiradiobutton(app.Chongiatrisaiso_DaoHam_ButtonGroup_2);
            app.Oh_DaoHam_Button_2.Text = 'O(h)';
            app.Oh_DaoHam_Button_2.Value = true;
            app.Oh2_DaoHam_Button_2 = uiradiobutton(app.Chongiatrisaiso_DaoHam_ButtonGroup_2);
            app.Oh2_DaoHam_Button_2.Text = 'O(h^2)';
            app.Choncahtinh_DaoHam_ButtonGroup_2 = uibuttongroup(app.DaohamTab_2);
            app.Choncahtinh_DaoHam_ButtonGroup_2.Title = 'Chọn dữ liệu đầu vào';
            app.MangXY_DaoHam_Button_2 = uiradiobutton(app.Choncahtinh_DaoHam_ButtonGroup_2);
            app.MangXY_DaoHam_Button_2.Text = 'Mảng X, Y';
            app.MangXY_DaoHam_Button_2.Value = true;
            app.Hamso_DaoHam_Button_2 = uiradiobutton(app.Choncahtinh_DaoHam_ButtonGroup_2);
            app.Hamso_DaoHam_Button_2.Text = 'Hàm số';
            app.TichPhanTab_2 = uitab(app.TabGroup_2);
            app.TichPhanTab_2.Title = 'Tích Phân';
            app.NhphmsEditFieldLabel_4 = uilabel(app.TichPhanTab_2);
            app.NhphmsEditFieldLabel_4.Text = 'Nhập hàm số';
            app.Hamso_2 = uieditfield(app.TichPhanTab_2, 'text');
            app.Hamso_2.Value = 'x^3*sin(x) + x*cos(x)';
            app.NhpNLabel_2 = uilabel(app.TichPhanTab_2);
            app.NhpNLabel_2.Text = 'Nhập N';
            app.dataN_2 = uieditfield(app.TichPhanTab_2, 'numeric');
            app.dataN_2.Value = 10;
            app.CntrnbEditFieldLabel_2 = uilabel(app.TichPhanTab_2);
            app.CntrnbEditFieldLabel_2.Text = 'Cận trên (b)';
            app.CanB_2 = uieditfield(app.TichPhanTab_2, 'numeric');
            app.CanB_2.Value = 2;
            app.CndiaEditFieldLabel_2 = uilabel(app.TichPhanTab_2);
            app.CndiaEditFieldLabel_2.Text = 'Cận dưới (a)';
            app.CanA_2 = uieditfield(app.TichPhanTab_2, 'numeric');
            app.Methods_2 = uibuttongroup(app.TichPhanTab_2);
            app.Methods_2.Title = 'Chọn phương pháp tính tích phân';
            app.HinhThang_2 = uiradiobutton(app.Methods_2);
            app.HinhThang_2.Text = 'Hình Thang';
            app.HinhThang_2.Value = true;
            app.Simpson13_2 = uiradiobutton(app.Methods_2);
            app.Simpson13_2.Text = 'Simpson 1/3';
            app.Simpson38_2 = uiradiobutton(app.Methods_2);
            app.Simpson38_2.Text = 'Simpson 3/8';
            app.CalcIntegral_2 = uibutton(app.TichPhanTab_2, 'push');
            app.CalcIntegral_2.Text = 'Tính ';
            app.ChonInput_2 = uibuttongroup(app.TichPhanTab_2);
            app.ChonInput_2.Title = 'Chọn dữ liệu đầu vào';
            app.VectorX_VectorY_2 = uiradiobutton(app.ChonInput_2);
            app.VectorX_VectorY_2.Text = 'Mảng x, Mảng y';
            app.VectorX_VectorY_2.Value = true;
            app.NhapHamSo_2 = uiradiobutton(app.ChonInput_2);
            app.NhapHamSo_2.Text = 'Hàm số';
            app.KetQua_2 = uilabel(app.TichPhanTab_2);
            app.KetQua_2.Text = '0';
            app.KetQuaLabel_2 = uilabel(app.TichPhanTab_2);
            app.KetQuaLabel_2.Text = 'Kết Quả';
            app.NhpdliuXEditFieldLabel_2 = uilabel(app.TichPhanTab_2);
            app.NhpdliuXEditFieldLabel_2.Text = 'Nhập dữ liệu X';
            app.Xdata_2 = uieditfield(app.TichPhanTab_2, 'text');
            app.Xdata_2.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuYEditFieldLabel_2 = uilabel(app.TichPhanTab_2);
            app.NhpdliuYEditFieldLabel_2.Text = 'Nhập dữ liệu Y';
            app.Ydata_2 = uieditfield(app.TichPhanTab_2, 'text');
            app.Ydata_2.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.IntroTab_2 = uitab(app.TabGroup_2);
            app.IntroTab_2.Title = 'Giới thiệu nhóm';
            app.Tree_2 = uitree(app.IntroTab_2);
            app.THANHVIEN_2 = uitreenode(app.Tree_2);
            app.THANHVIEN_2.Text = '   THÀNH VIÊN';
            app.DinhDungNode_2 = uitreenode(app.THANHVIEN_2);
            app.DinhDungNode_2.Text = 'Lê Đình Dũng';
            app.VanHyNode_2 = uitreenode(app.THANHVIEN_2);
            app.VanHyNode_2.Text = 'Đặng Văn Hy';
            app.QuangDungNode_2 = uitreenode(app.THANHVIEN_2);
            app.QuangDungNode_2.Text = 'Phạm Quang Dũng';
            app.NguynHuyDuNode_2 = uitreenode(app.THANHVIEN_2);
            app.NguynHuyDuNode_2.Text = 'Nguyễn Huy Du';
            app.BaoQuocNode_2 = uitreenode(app.THANHVIEN_2);
            app.BaoQuocNode_2.Text = 'Phạm Bảo Quốc';
            app.MinhQuangNode_2 = uitreenode(app.THANHVIEN_2);
            app.MinhQuangNode_2.Text = 'Đỗ Minh Quang';
            app.GIITHIUNHMLabel_2 = uilabel(app.IntroTab_2);
            app.GIITHIUNHMLabel_2.Text = 'GIỚI THIỆU NHÓM';
            app.MSSVLabel_2 = uilabel(app.IntroTab_2);
            app.MSSVLabel_2.Text = 'MSSV ';
            app.MSSV_2 = uieditfield(app.IntroTab_2, 'text');
            app.MSSV_2.Editable = 'off';
            app.NHIMVEditFieldLabel_2 = uilabel(app.IntroTab_2);
            app.NHIMVEditFieldLabel_2.Text = 'NHIỆM VỤ';
            app.NHIEMVU_2 = uieditfield(app.IntroTab_2, 'text');
            app.NHIEMVU_2.Editable = 'off';
            app.NGYSINHEditFieldLabel_2 = uilabel(app.IntroTab_2);
            app.NGYSINHEditFieldLabel_2.Text = 'NGÀY SINH';
            app.NGAYSINH_2 = uieditfield(app.IntroTab_2, 'text');
            app.NGAYSINH_2.Editable = 'off';
            app.CHCVEditFieldLabel_2 = uilabel(app.IntroTab_2);
            app.CHCVEditFieldLabel_2.Text = 'CHỨC VỤ';
            app.CHUCVU_2 = uieditfield(app.IntroTab_2, 'text');
            app.CHUCVU_2.Editable = 'off';
            app.LPEditFieldLabel_2 = uilabel(app.IntroTab_2);
            app.LPEditFieldLabel_2.Text = 'LỚP';
            app.LOP_2 = uieditfield(app.IntroTab_2, 'text');
            app.LOP_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end